classdef CommunicationLab < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ConnectButton              matlab.ui.control.Button
        DisconnectButton           matlab.ui.control.Button
        ADCUPDATEButton            matlab.ui.control.Button
        PotReadingLabel            matlab.ui.control.Label
        RedLEDButtonGroup          matlab.ui.container.ButtonGroup
        RedOFF                     matlab.ui.control.ToggleButton
        RedON                      matlab.ui.control.ToggleButton
        YellowLEDButtonGroup       matlab.ui.container.ButtonGroup
        YellowOFF                  matlab.ui.control.ToggleButton
        YellowON                   matlab.ui.control.ToggleButton
        GreenLEDButtonGroup        matlab.ui.container.ButtonGroup
        GreenOFF                   matlab.ui.control.ToggleButton
        GreenON                    matlab.ui.control.ToggleButton
        BlueLEDButtonGroup         matlab.ui.container.ButtonGroup
        BlueOFF                    matlab.ui.control.ToggleButton
        BlueON                     matlab.ui.control.ToggleButton
        RGBLEDDropDownLabel        matlab.ui.control.Label
        RGBLEDDropDown             matlab.ui.control.DropDown
        PhotoReadingLabel          matlab.ui.control.Label
        PUSHBUTTONPanel            matlab.ui.container.Panel
        redLabel                   matlab.ui.control.Label
        yellowLabel                matlab.ui.control.Label
        greenLabel                 matlab.ui.control.Label
        blueLabel                  matlab.ui.control.Label
        PhotocellReadingLabel      matlab.ui.control.Label
        PotentiometerReadingLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        s
        redCounter
        yellowCounter
        greenCounter
        blueCounter
    end
    
    methods (Access = private)
        function connect(app)
            open_ports = instrfind('Type','serial','Status','open');
            if ~isempty(open_ports)
                fclose(open_ports);
            end
            app.s = serial("COM3", ...
                "BaudRate", 9600, ...
                "Terminator", 10, ...
                "BytesAvailableFcnMode", "terminator", ...
                "BytesAvailableFcn", @app.bytesRecieved);
            fopen(app.s);
            fprintf("\nConnected!\n");
        end
        
        function disconnect(app)
            fclose(app.s);
            fprintf("\nDisconnected!\n");
            
        end
        
        function bytesRecieved(app, serialObj, eventData)
            message = fscanf(serialObj);
            message = strtrim(message);
            fprintf("Received: %s\n", message);
            % Complete message match area...
            if strcmpi(message, "PRESSED RED")
                % TODO: Increment a red counter!
                app.redCounter = app.redCounter+1;
                app.redLabel.Text = num2str(app.redCounter);
            end
            
            if strcmpi(message, "PRESSED YELLOW")
                % TODO: Increment a yellow counter!
                app.yellowCounter = app.yellowCounter+1;
                app.yellowLabel.Text = num2str(app.yellowCounter);
                
            end
            
            if strcmpi(message, "PRESSED GREEN")
                % TODO: Increment a yellow counter!
                app.greenCounter = app.greenCounter+1;
                app.greenLabel.Text = num2str(app.greenCounter);
                
            end
            
            if strcmpi(message, "PRESSED BLUE")
                % TODO: Increment a yellow counter!
                app.blueCounter = app.blueCounter+1;
                app.blueLabel.Text = num2str(app.blueCounter);
                
            end
            
            % Partial match area
            messageArray = split(message, " ");
            if strcmpi(messageArray(1), "POT")
                app.PotReadingLabel.Text = messageArray(3);
                app.PhotoReadingLabel.Text  = messageArray(2);
                
            end
            
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            app.connect()
            app.redCounter = 0;
            app.yellowCounter = 0;
            app.greenCounter = 0;
            app.blueCounter = 0;
            
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            app.connect()
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            app.disconnect()
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.disconnect()
            delete(app)
        end

        % Callback function
        function LEDONButtonPushed(app, event)
            fprintf(app.s, "ON");
        end

        % Callback function
        function LEDOFFButtonPushed(app, event)
            fprintf(app.s, "OFF");
        end

        % Button pushed function: ADCUPDATEButton
        function ADCUPDATEButtonPushed(app, event)
            fprintf(app.s, "ADC");
        end

        % Selection changed function: RedLEDButtonGroup
        function RedLEDButtonGroupSelectionChanged(app, event)
            selectedButton = app.RedLEDButtonGroup.SelectedObject;
            if strcmpi(selectedButton.Text, 'ON')
                fprintf(app.s,"REDON");
            elseif strcmpi(selectedButton.Text, 'OFF')
                fprintf(app.s,"REDOFF");
            end
        end

        % Selection changed function: YellowLEDButtonGroup
        function YellowLEDButtonGroupSelectionChanged(app, event)
            selectedButton = app.YellowLEDButtonGroup.SelectedObject;
            if strcmpi(selectedButton.Text, 'ON')
                fprintf(app.s,"YELLOWON");
            elseif strcmpi(selectedButton.Text, 'OFF')
                fprintf(app.s,"YELLOWOFF");
            end
        end

        % Selection changed function: GreenLEDButtonGroup
        function GreenLEDButtonGroupSelectionChanged(app, event)
            selectedButton = app.GreenLEDButtonGroup.SelectedObject;
            if strcmpi(selectedButton.Text, 'ON')
                fprintf(app.s,"GREENON");
            elseif strcmpi(selectedButton.Text, 'OFF')
                fprintf(app.s,"GREENOFF");
            end
        end

        % Selection changed function: BlueLEDButtonGroup
        function BlueLEDButtonGroupSelectionChanged(app, event)
            selectedButton = app.BlueLEDButtonGroup.SelectedObject;
            if strcmpi(selectedButton.Text, 'ON')
                fprintf(app.s,"BLUEON");
            elseif strcmpi(selectedButton.Text, 'OFF')
                fprintf(app.s,"BLUEOFF");
            end
        end

        % Value changed function: RGBLEDDropDown
        function RGBLEDDropDownValueChanged(app, event)
            value = app.RGBLEDDropDown.Value;
            if strcmpi(value, 'OFF')
                fprintf(app.s,"RGBOFF");
            elseif strcmpi(value, 'BLUE')
                fprintf(app.s,"RGBBLUE");
            elseif strcmpi(value, 'GREEN')
                fprintf(app.s,"RGBGREEN");
            elseif strcmpi(value, 'CYAN')
                fprintf(app.s,"RGBCYAN");
            elseif strcmpi(value, 'RED')
                fprintf(app.s,"RGBRED");
            elseif strcmpi(value, 'PURPLE')
                fprintf(app.s,"RGBPURPLE");
            elseif strcmpi(value, 'YELLOW')
                fprintf(app.s,"RGBYELLOW");
            elseif strcmpi(value, 'WHITE')
                fprintf(app.s,"RGBWHITE");
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ConnectButton = uibutton(app.UIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.DisconnectButton = uibutton(app.UIFigure, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Text = 'Disconnect';
            app.ADCUPDATEButton = uibutton(app.UIFigure, 'push');
            app.ADCUPDATEButton.ButtonPushedFcn = createCallbackFcn(app, @ADCUPDATEButtonPushed, true);
            app.ADCUPDATEButton.Text = 'ADC UPDATE';
            app.PotReadingLabel = uilabel(app.UIFigure);
            app.PotReadingLabel.Text = '1023';
            app.RedLEDButtonGroup = uibuttongroup(app.UIFigure);
            app.RedLEDButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @RedLEDButtonGroupSelectionChanged, true);
            app.RedLEDButtonGroup.Title = 'Red LED';
            app.RedOFF = uitogglebutton(app.RedLEDButtonGroup);
            app.RedOFF.Text = 'OFF';
            app.RedOFF.Value = true;
            app.RedON = uitogglebutton(app.RedLEDButtonGroup);
            app.RedON.Text = 'ON';
            app.YellowLEDButtonGroup = uibuttongroup(app.UIFigure);
            app.YellowLEDButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @YellowLEDButtonGroupSelectionChanged, true);
            app.YellowLEDButtonGroup.Title = 'Yellow LED';
            app.YellowOFF = uitogglebutton(app.YellowLEDButtonGroup);
            app.YellowOFF.Text = 'OFF';
            app.YellowOFF.Value = true;
            app.YellowON = uitogglebutton(app.YellowLEDButtonGroup);
            app.YellowON.Text = 'ON';
            app.GreenLEDButtonGroup = uibuttongroup(app.UIFigure);
            app.GreenLEDButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @GreenLEDButtonGroupSelectionChanged, true);
            app.GreenLEDButtonGroup.Title = 'Green LED';
            app.GreenOFF = uitogglebutton(app.GreenLEDButtonGroup);
            app.GreenOFF.Text = 'OFF';
            app.GreenOFF.Value = true;
            app.GreenON = uitogglebutton(app.GreenLEDButtonGroup);
            app.GreenON.Text = 'ON';
            app.BlueLEDButtonGroup = uibuttongroup(app.UIFigure);
            app.BlueLEDButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @BlueLEDButtonGroupSelectionChanged, true);
            app.BlueLEDButtonGroup.Title = 'Blue LED';
            app.BlueOFF = uitogglebutton(app.BlueLEDButtonGroup);
            app.BlueOFF.Text = 'OFF';
            app.BlueOFF.Value = true;
            app.BlueON = uitogglebutton(app.BlueLEDButtonGroup);
            app.BlueON.Text = 'ON';
            app.RGBLEDDropDownLabel = uilabel(app.UIFigure);
            app.RGBLEDDropDownLabel.Text = 'RGB LED';
            app.RGBLEDDropDown = uidropdown(app.UIFigure);
            app.RGBLEDDropDown.Items = {'OFF', 'BLUE', 'GREEN', 'CYAN', 'RED', 'PURPLE', 'YELLOW', 'WHITE'};
            app.RGBLEDDropDown.ValueChangedFcn = createCallbackFcn(app, @RGBLEDDropDownValueChanged, true);
            app.RGBLEDDropDown.Value = 'OFF';
            app.PhotoReadingLabel = uilabel(app.UIFigure);
            app.PhotoReadingLabel.Text = '1023';
            app.PUSHBUTTONPanel = uipanel(app.UIFigure);
            app.PUSHBUTTONPanel.Title = 'PUSHBUTTON';
            app.redLabel = uilabel(app.PUSHBUTTONPanel);
            app.redLabel.Text = '0';
            app.yellowLabel = uilabel(app.PUSHBUTTONPanel);
            app.yellowLabel.Text = '0';
            app.greenLabel = uilabel(app.PUSHBUTTONPanel);
            app.greenLabel.Text = '0';
            app.blueLabel = uilabel(app.PUSHBUTTONPanel);
            app.blueLabel.Text = '0';
            app.PhotocellReadingLabel = uilabel(app.UIFigure);
            app.PhotocellReadingLabel.Text = 'Photocell Reading';
            app.PotentiometerReadingLabel = uilabel(app.UIFigure);
            app.PotentiometerReadingLabel.Text = 'Potentiometer Reading';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CommunicationLab

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end