classdef projectGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        BlueLight         matlab.ui.control.Lamp
        GreenLight        matlab.ui.control.Lamp
        RedLight          matlab.ui.control.Lamp
        YellowLight       matlab.ui.control.Lamp
        REFLEXTESTLabel   matlab.ui.control.Label
        PRESSTHECORRECTPUSHBUTTONASFASTASPOSSIBLELabel  matlab.ui.control.Label
        Display           matlab.ui.control.Label
        STARTButton       matlab.ui.control.Button
        ConnectButton     matlab.ui.control.Button
        DisconnectButton  matlab.ui.control.Button
        YoudiditinLabel   matlab.ui.control.Label
        SecondsLabel      matlab.ui.control.Label
        RestartButton     matlab.ui.control.Button
    end

    
    properties (Access = private)
        t % Description
        s     
    end
    
    methods (Access = private)
        function connect(app)
            open_ports = instrfind('Type','serial','Status','open');
            if ~isempty(open_ports)
                fclose(open_ports);
            end
            app.s = serial("COM3", ...
                "BaudRate", 9600, ...
                "Terminator", 10, ...
                "BytesAvailableFcnMode", "terminator", ...
                "BytesAvailableFcn", @app.bytesRecieved);
            fopen(app.s);
            fprintf("\nConnected!\n");
        end
        function disconnect(app)
            fclose(app.s);
            fprintf("\nDisconnected!\n");
            fprintf("Received: %s\n", message);
            
        end
        function bytesRecieved(app, serialObj, eventData)
            message = fscanf(serialObj);
            message = strtrim(message);
            % Complete message match area...
            if strcmpi(message, "ERROR")
                app.Display.Text = "WRONG BUTTON! TRY AGAIN!";
                app.LightReset();
            end
                  
            % Partial match area
            messageArray = split(message, " ");
            if strcmpi(messageArray(1), "POT")
                messageArray(2)
                app.Display.Text =  messageArray(2);
                app.LightReset();
            end
            
        end
        
        
        function SendSignal(app,obj, event, string_arg)
            randomColor= randi([1 4], 1);
            switch randomColor
                case 1
                    app.RedLight.Enable = 'on';
                    fprintf(app.s, "START RED");
                case 2
                    app.YellowLight.Enable = 'on';
                    fprintf(app.s, "START YELLOW");
                case 3
                    app.GreenLight.Enable = 'on';
                    fprintf(app.s, "START GREEN");
                case 4
                    app.BlueLight.Enable = 'on';
                    fprintf(app.s, "START BLUE");
                    
            end        
        end


        function LightReset(app)
            app.RedLight.Enable = 'off';
            app.GreenLight.Enable = 'off';
            app.YellowLight.Enable = 'off';
            app.BlueLight.Enable = 'off';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc     
            app.LightReset();
            app.connect();
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.t)
            app.disconnect();
            delete(app)            
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            randStart = 3+2*rand();
            app.t = timer('StartDelay',randStart);
            app.t.TimerFcn = @app.SendSignal;
            start(app.t)
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            app.connect();
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            app.disconnect();
        end

        % Button pushed function: RestartButton
        function RestartButtonPushed(app, event)
            app.LightReset();
            fprintf(app.s, "RESTART");
         
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.BlueLight = uilamp(app.UIFigure);
            app.GreenLight = uilamp(app.UIFigure);
            app.RedLight = uilamp(app.UIFigure);
            app.YellowLight = uilamp(app.UIFigure);
            app.REFLEXTESTLabel = uilabel(app.UIFigure);
            app.REFLEXTESTLabel.Text = 'REFLEX TEST';
            app.PRESSTHECORRECTPUSHBUTTONASFASTASPOSSIBLELabel = uilabel(app.UIFigure);
            app.PRESSTHECORRECTPUSHBUTTONASFASTASPOSSIBLELabel.Text = 'PRESS THE CORRECT PUSHBUTTON AS FAST AS POSSIBLE';
            app.Display = uilabel(app.UIFigure);
            app.Display.Text = '?';
            app.STARTButton = uibutton(app.UIFigure, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.ConnectButton = uibutton(app.UIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.DisconnectButton = uibutton(app.UIFigure, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Text = 'Disconnect';
            app.YoudiditinLabel = uilabel(app.UIFigure);
            app.YoudiditinLabel.Text = 'You did it in ';
            app.SecondsLabel = uilabel(app.UIFigure);
            app.SecondsLabel.Text = 'Seconds';
            app.RestartButton = uibutton(app.UIFigure, 'push');
            app.RestartButton.ButtonPushedFcn = createCallbackFcn(app, @RestartButtonPushed, true);
            app.RestartButton.Text = 'Restart';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = projectGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end